classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        LoadImageButton        matlab.ui.control.Button
        BrightnessSliderLabel  matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
        UIAxes2                matlab.ui.control.UIAxes
        ContrastSliderLabel    matlab.ui.control.Label
        ContrastSlider         matlab.ui.control.Slider
        EditField              matlab.ui.control.NumericEditField
        EditField_2            matlab.ui.control.NumericEditField
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        HistEqButton           matlab.ui.control.Button
        EditField_3            matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function DrawHistogram(app)
            cla(app.UIAxes3,'reset')
            global imageType;
            global G;
            global histArrayGS;
            global histArrayHSV;
            if strcmp(imageType,'grayscale')
                histArrayGS = zeros(256, 1);
                [M, N] = size(G);
                for i = 1:M
                    for j = 1:N
                        histArrayGS(G(i,j)+1,1) = histArrayGS(G(i,j)+1,1) + 1;
                    end
                end
                bar(histArrayGS,'Parent', app.UIAxes3);
            elseif strcmp(imageType,'rgb')
                histArrayHSV = zeros(256, 1);
                histArrayR = zeros(256, 1);
                histArrayG = zeros(256, 1);
                histArrayB = zeros(256, 1);
                redChannel = G(:, :, 1);
                greenChannel = G(:, :, 2);
                blueChannel = G(:, :, 3);
                I_hsv = rgb2hsv(G);
                v_comp = I_hsv(:,:,3) * 255;
                [M, N] = size(redChannel);
                for c = 1:3
                    for i = 1:M
                        for j = 1:N
                            if c == 1
                                histArrayR(redChannel(i,j)+1,1) = histArrayR(redChannel(i,j)+1,1) + 1;
                            elseif c==2
                                histArrayG(greenChannel(i,j)+1,1) = histArrayG(greenChannel(i,j)+1,1) + 1;
                            else
                                histArrayB(blueChannel(i,j)+1,1) = histArrayB(blueChannel(i,j)+1,1) + 1;
                            end
                        end
                    end
                end
                [K, L] = size(v_comp);
                for x = 1:K
                    for y = 1:L
                        histArrayHSV(v_comp(x,y)+1,1) = histArrayHSV(v_comp(x,y)+1,1) + 1;
                    end
                end
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,histArrayR,'r','LineWidth',2,'MarkerSize',10);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,histArrayG,'g','LineWidth',2,'MarkerSize',10);
                hold(app.UIAxes3,'on');
                plot(app.UIAxes3,histArrayB,'b','LineWidth',2,'MarkerSize',10);
                hold(app.UIAxes3,'off');
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global I;
            global G;
            global imageType;
            [fileName, pathName] = uigetfile({'*.jpg';'*.jpeg'}, 'Select Image');
            file = [pathName fileName];
            I = imread(file);
            if size(I,3)==3
                imageType = 'rgb';
            else
                imageType = 'grayscale';
            end
            app.EditField_3.Value = imageType;
            imshow(I,'Parent',app.UIAxes);
            value = app.BrightnessSlider.Value;
            app.EditField.Value = value;
            G = I  + value;
            imshow(G,'Parent',app.UIAxes2);
            DrawHistogram(app);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            global I;
            global G;
            value = app.BrightnessSlider.Value;
            app.EditField.Value = value;
            G = I + value;
            imshow(G,'Parent',app.UIAxes2);
            DrawHistogram(app);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            global I;
            global G;
            value = app.ContrastSlider.Value;
            app.EditField_2.Value = value;
            G = I * value;
            imshow(G,'Parent',app.UIAxes2);
            DrawHistogram(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.BrightnessSlider.Value = value; 
            global I;
            global G;
            G = I + value;
            imshow(G,'Parent',app.UIAxes2);
            DrawHistogram(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.ContrastSlider.Value = value; 
            global I;
            global G;
            G = (I - mean(I)) * value ;
            imshow(G,'Parent',app.UIAxes2);
            DrawHistogram(app);
        end

        % Button pushed function: HistEqButton
        function HistEqButtonPushed(app, event)
            cla(app.UIAxes4,'reset')
            global G;
            global histEqIm;
            global imageType;
            global histArrayGS;
            global histArrayHSV;
            
            if strcmp(imageType,'grayscale')
                histEqIm = G;
                [M, N] = size(G);
                histArrayGS=histArrayGS/(M*N);
                for k=1:256
                    C(k)=uint8(sum(histArrayGS(1:k))*255);
                end
                for i=1:size(histEqIm,1)
                    for j=1:size(histEqIm,2)
                            histEqIm(i,j)=C(G(i,j)+1);
                    end
                end
            elseif strcmp(imageType,'rgb')
                histEqIm = rgb2hsv(G);
                v_comp = histEqIm(:,:,3);
                [M, N] = size(G);
                histArrayHSV=histArrayHSV/(M*N);
                for k=1:256
                    C(k)=sum(histArrayHSV(1:k))*255;
                end
                for i=1:size(histEqIm,1)
                    for j=1:size(histEqIm,2)
                            v_comp(i,j)=C(G(i,j)+1);
                    end
                end
                histEqIm(:,:,3) = v_comp;
                histEqIm = hsv2rgb(histEqIm);
            end
            
            if strcmp(imageType,'grayscale')
                histEqImArray = zeros(256, 1);
                [M, N] = size(histEqIm);
                for i = 1:M
                    for j = 1:N
                        histEqImArray(histEqIm(i,j)+1,1) = histEqImArray(histEqIm(i,j)+1,1) + 1;
                    end
                end
                bar(histEqImArray,'Parent', app.UIAxes4);
            elseif strcmp(imageType,'rgb')
            end
            imshow(histEqIm,'Parent',app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-256 256];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 10];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 1;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.HistEqButton = uibutton(app.UIFigure, 'push');
            app.HistEqButton.ButtonPushedFcn = createCallbackFcn(app, @HistEqButtonPushed, true);
            app.HistEqButton.Text = 'HistEq';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end